classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        ButtonGroup           matlab.ui.container.ButtonGroup    % Error Co...
        RadioButton           matlab.ui.control.RadioButton      % CRC32
        RadioButton2          matlab.ui.control.RadioButton      % 2z5
        RadioButton7          matlab.ui.control.RadioButton      % Parity Bit
        ButtonGroup2          matlab.ui.container.ButtonGroup    % ARQ Prot...
        RadioButton3          matlab.ui.control.RadioButton      % Stop-and...
        RadioButton4          matlab.ui.control.RadioButton      % Go-Back-N
        LabelNumericEditField matlab.ui.control.Label            % Packet Size
        NumericEditField      matlab.ui.control.NumericEditField % [-Inf Inf]
        ButtonGroup3          matlab.ui.container.ButtonGroup    % Transfer...
        RadioButton5          matlab.ui.control.RadioButton      % Binary S...
        RadioButton6          matlab.ui.control.RadioButton      % Binary E...
        RadioButton8          matlab.ui.control.RadioButton      % Cyclic E...
        Button                matlab.ui.control.Button           % SIMULATE
        LabelSlider           matlab.ui.control.Label            % Bit Erro...
        Slider                matlab.ui.control.Slider           % [0 5.000...
        Label                 matlab.ui.control.Label            % Transfer...
        NumericEditField3     matlab.ui.control.NumericEditField % [0 Inf]
        Label2                matlab.ui.control.Label            % Packets ...
        NumericEditField4     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField        matlab.ui.control.Label            % Data Sav...
        EditField             matlab.ui.control.EditField        % data.txt
        LabelTextArea         matlab.ui.control.Label            % Simulati...
        TextArea              matlab.ui.control.TextArea        
    end


    properties (Access = private)
        SIMULATION = Simulation % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % ButtonGroup selection change function
        function changeErrCtrlVer(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if(selectedButton == app.RadioButton)
                app.SIMULATION.setErrorControlVer('CRC32');
            elseif(selectedButton == app.RadioButton2)
                app.SIMULATION.setErrorControlVer('2z5');
            elseif(selectedButton == app.RadioButton7)
                app.SIMULATION.setErrorControlVer('PB');
            end       
        end

        % ButtonGroup2 selection change function
        function changeProtocolVer(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            if(selectedButton == app.RadioButton3)
                app.SIMULATION.setProtocolVer('SAW');
            elseif(selectedButton == app.RadioButton4)
                app.SIMULATION.setProtocolVer('GBN');
            end   
        end

        % NumericEditField value changed function
        function changePacketSize(app)
            value = app.NumericEditField.Value;
            app.SIMULATION.setPacketSize(value);
        end

        % Button button pushed function
        function runSimulation(app)
            app.SIMULATION.simulate;
            % Reading simulation data
            while (isempty(app.SIMULATION.str_LastSimulationData)) % waiting for simulation to end
            end
            str_text = app.TextArea.Value; 
            str_text{end+1} = app.SIMULATION.str_LastSimulationData;
            app.TextArea.Value = str_text;
            app.SIMULATION.str_LastSimulationData = zeros(0);
            
        end

        % ButtonGroup3 selection change function
        function changeTransferModel(app, event)
            selectedButton = app.ButtonGroup3.SelectedObject;
            if(selectedButton == app.RadioButton5)
                app.SIMULATION.setModelVer('BSC');
            elseif(selectedButton == app.RadioButton6)
                app.SIMULATION.setModelVer('BEC');
            elseif(selectedButton == app.RadioButton8)
                app.SIMULATION.setModelVer('CEC');
            end       
        end

        % Slider value changed function
        function changeErrorRate(app)
            app.SIMULATION.setErrorRate(app.Slider.Value);
        end

        % NumericEditField3 value changed function
        function changeTransferRate(app)
            app.SIMULATION.setBitTransRate(app.NumericEditField3.Value);
        end

        % NumericEditField4 value changed function
        function changePacketsCount(app)
            app.SIMULATION.setPacketsCount(app.NumericEditField4.Value);
        end

        % EditField value changed function
        function setSaveFilename(app)
            app.SIMULATION.setSaveFilename(app.EditField.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeErrCtrlVer, true);
            app.ButtonGroup.Title = 'Error Control';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'CRC32';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = '2z5';
            app.RadioButton7 = uiradiobutton(app.ButtonGroup);
            app.RadioButton7.Text = 'Parity Bit';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @changeProtocolVer, true);
            app.ButtonGroup2.Title = 'ARQ Protocol';
            app.ButtonGroup2.Units = 'pixels';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton3.Text = 'Stop-and-Wait';
            app.RadioButton3.Value = true;
            app.RadioButton4 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton4.Text = 'Go-Back-N';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Packet Size';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @changePacketSize);
            app.NumericEditField.ValueDisplayFormat = '%11.9g';
            app.NumericEditField.Value = 32;
            app.ButtonGroup3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @changeTransferModel, true);
            app.ButtonGroup3.Title = 'Transfer Model';
            app.ButtonGroup3.Units = 'pixels';
            app.RadioButton5 = uiradiobutton(app.ButtonGroup3);
            app.RadioButton5.Text = 'Binary Symmetric Channel';
            app.RadioButton5.Value = true;
            app.RadioButton6 = uiradiobutton(app.ButtonGroup3);
            app.RadioButton6.Text = 'Binary Erasure Channel';
            app.RadioButton8 = uiradiobutton(app.ButtonGroup3);
            app.RadioButton8.Text = 'Cyclic Error Channel';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @runSimulation);
            app.Button.Text = 'SIMULATE';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Bit Error Probability';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 0.5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @changeErrorRate);
            app.Slider.Value = 0.002;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Transfer rate [b/s]';
            app.NumericEditField3 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @changeTransferRate);
            app.NumericEditField3.Limits = [0 Inf];
            app.NumericEditField3.ValueDisplayFormat = '%11.9g';
            app.NumericEditField3.Value = 1000;
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Packets Count';
            app.NumericEditField4 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @changePacketsCount);
            app.NumericEditField4.ValueDisplayFormat = '%11.9g';
            app.NumericEditField4.Value = 10;
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Data Save Filename';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @setSaveFilename);
            app.EditField.Value = 'data.txt';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Simulation Results';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end